classdef GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        ESTOPButton               matlab.ui.control.Button
        HOMEButton_2              matlab.ui.control.Button
        PERFORMButton             matlab.ui.control.Button
        RACK1DropDownLabel        matlab.ui.control.Label
        RACK1LDropDown            matlab.ui.control.DropDown
        RACK2DropDownLabel        matlab.ui.control.Label
        RACK2LDropDown            matlab.ui.control.DropDown
        PERFORMButton_2           matlab.ui.control.Button
        RACK2DropDown_3Label      matlab.ui.control.Label
        RACK2RDropDown            matlab.ui.control.DropDown
        RACK1DropDown_2Label      matlab.ui.control.Label
        RACK1RDropDown            matlab.ui.control.DropDown
        ForwardButton             matlab.ui.control.Button
        BackButton                matlab.ui.control.Button
        RightButton               matlab.ui.control.Button
        LeftButton                matlab.ui.control.Button
        TeachModeCheckBox         matlab.ui.control.CheckBox
        UpButton                  matlab.ui.control.Button
        DownButton                matlab.ui.control.Button
        FromLabel                 matlab.ui.control.Label
        FromLabel_2               matlab.ui.control.Label
        ToLabel                   matlab.ui.control.Label
        ToLabel_2                 matlab.ui.control.Label
        CARTESIANLabel            matlab.ui.control.Label
        JOINTLabel                matlab.ui.control.Label
        JOINT1Minus               matlab.ui.control.Button
        JOINT1Label               matlab.ui.control.Label
        JOINT1Plus                matlab.ui.control.Button
        JOINT2Minus               matlab.ui.control.Button
        JOINT2Label               matlab.ui.control.Label
        JOINT2Plus                matlab.ui.control.Button
        JOINT3Minus               matlab.ui.control.Button
        JOINT3Label               matlab.ui.control.Label
        JOINT3Plus                matlab.ui.control.Button
        JOINT4Minus               matlab.ui.control.Button
        JOINT4Label               matlab.ui.control.Label
        JOINT4Plus                matlab.ui.control.Button
        IncrementDropDownLabel    matlab.ui.control.Label
        IncrementDropDown         matlab.ui.control.DropDown
        IncrementDropDown_2Label  matlab.ui.control.Label
        IncrementDropDown_2       matlab.ui.control.DropDown
        XLabel                    matlab.ui.control.Label
        XEditField                matlab.ui.control.EditField
        CONNECTButton             matlab.ui.control.Button
        MMLabel                   matlab.ui.control.Label
        DegLabel                  matlab.ui.control.Label
        StoppedLampLabel          matlab.ui.control.Label
        StoppedLamp               matlab.ui.control.Lamp
        RESUMEButton              matlab.ui.control.Button
        R11                       matlab.ui.control.CheckBox
        RACK1Label                matlab.ui.control.Label
        R12                       matlab.ui.control.CheckBox
        R13                       matlab.ui.control.CheckBox
        R14                       matlab.ui.control.CheckBox
        R15                       matlab.ui.control.CheckBox
        R16                       matlab.ui.control.CheckBox
        R21                       matlab.ui.control.CheckBox
        RACK2Label                matlab.ui.control.Label
        R22                       matlab.ui.control.CheckBox
        R23                       matlab.ui.control.CheckBox
        R24                       matlab.ui.control.CheckBox
        R25                       matlab.ui.control.CheckBox
        R26                       matlab.ui.control.CheckBox
        YEditFieldLabel           matlab.ui.control.Label
        YEditField                matlab.ui.control.EditField
        ZLabel                    matlab.ui.control.Label
        ZEditField                matlab.ui.control.EditField
        POSITIONLabel             matlab.ui.control.Label
        J1EditFieldLabel          matlab.ui.control.Label
        J1EditField               matlab.ui.control.EditField
        J2EditFieldLabel          matlab.ui.control.Label
        J2EditField               matlab.ui.control.EditField
        J3EditFieldLabel          matlab.ui.control.Label
        J3EditField               matlab.ui.control.EditField
        J4EditFieldLabel          matlab.ui.control.Label
        J4EditField               matlab.ui.control.EditField
        DISCONNECTButton          matlab.ui.control.Button
        CALIBRATERACKSButton      matlab.ui.control.Button
        GripperButton             matlab.ui.control.Button
        GripperButton_2           matlab.ui.control.Button
        LIFTDOBOTButton           matlab.ui.control.Button
    end

    
    properties (Access = private)
        robot1; % Description
        cartesianDisIncrement = 0.01;
        jointAngIncrement = deg2rad(10);
        teachMode;
        cartesianDirection;
        cartesianAxis;
        jointDirection;
        jointNumber;
        lampColour = 0;
        
    end
    
    methods (Access = private)
        
        function UpdateDropDowns(app)
            
            rackState = app.robot1.GetRackState();
            rack1 = rackState(1, 1:6);
            rack2 = rackState(1, 7:12);
            
            % RACK 1 LEFT
            options = {};
            for i = 1 : length(rack1)
                if rack1(i) == 1
                    char = cellstr(num2str(i));
                    options(end + 1) = char;
                end
            end
            app.RACK1LDropDown.Items = options;
            
            % RACK 2 RIGHT
            options = {};
            for i = 1 : length(rack2)
                if rack2(i) == 1
                    char = cellstr(num2str(i));
                    options(end + 1) = char;
                end
            end
            app.RACK2RDropDown.Items = options;
            
            % RACK 2 LEFT
            options = {};
            for i = 1 : length(rack2)
                if rack2(i) == 0
                    char = cellstr(num2str(i));
                    options(end + 1) = char;
                end
            end
            app.RACK2LDropDown.Items = options;
            
            % RACK 1 RIGHT
            options = {};
            for i = 1 : length(rack1)
                if rack1(i) == 0
                    char = cellstr(num2str(i));
                    options(end + 1) = char;
                end
            end
            app.RACK1RDropDown.Items = options;
        end
        
        function UpdatePosition(app)
            
            cartesian = app.robot1.GetEndEffectorPosition();
            joint = app.robot1.GetJointStates();
            
            % Cartesian position
            app.XEditField.Value = num2str(cartesian(1));
            app.YEditField.Value = num2str(cartesian(2));
            app.ZEditField.Value = num2str(cartesian(3));
            
            % Joint position
            
            app.J1EditField.Value = num2str(rad2deg(joint(1)));
            app.J2EditField.Value = num2str(rad2deg(joint(2)));
            app.J3EditField.Value = num2str(rad2deg(joint(3)));
            app.J4EditField.Value = num2str(rad2deg(joint(4)));
            
        end
        
        function UpdateCheckBoxes(app)
            rackState = app.robot1.GetRackState();
            
            app.R11.Value = rackState(1);
            app.R12.Value = rackState(2);
            app.R13.Value = rackState(3);
            app.R14.Value = rackState(4);
            app.R15.Value = rackState(5);
            app.R16.Value = rackState(6);
            app.R21.Value = rackState(7);
            app.R22.Value = rackState(8);
            app.R23.Value = rackState(9);
            app.R24.Value = rackState(10);
            app.R25.Value = rackState(11);
            app.R26.Value = rackState(12);
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ESTOPButton
        function ESTOPButtonPushed(app, event)
            if app.lampColour == 0
                app.StoppedLamp.Color = 'green';
                app.lampColour = 1;
                
            elseif app.lampColour == 1
                app.StoppedLamp.Color = 'red';
                app.lampColour = 0;
            end
            
            app.robot1.EStopDobot();
            
                
        end

        % Button pushed function: CONNECTButton
        function CONNECTButtonPushed(app, event)
            rosinit;
            app.robot1 = DobotControl();
            disp('Robot Ready!');
        end

        % Button pushed function: HOMEButton_2
        function HOMEButton_2Pushed(app, event)
            app.robot1.HomeDobot();
            app.UpdatePosition();
        end

        % Value changed function: IncrementDropDown
        function IncrementDropDownValueChanged(app, event)
            if app.IncrementDropDown.Value == '10'
                app.cartesianDisIncrement = 0.01;
            end
            
            if app.IncrementDropDown.Value == '5'
                app.cartesianDisIncrement = 0.005;
            end
            
            if app.IncrementDropDown.Value == '1'
                app.cartesianDisIncrement = 0.001;
            end
        end

        % Value changed function: IncrementDropDown_2
        function IncrementDropDown_2ValueChanged(app, event)
            app.jointAngIncrement = app.IncrementDropDown_2.Value;
            disp(app.jointAngIncrement);
            
            if app.IncrementDropDown_2.Value == '10'
                app.jointAngIncrement = deg2rad(10);
            end
            
            if app.IncrementDropDown_2.Value == '5'
                app.jointAngIncrement = deg2rad(5);
            end
            
            if app.IncrementDropDown_2.Value == '1'
                app.jointAngIncrement = deg2rad(1);
            end
        end

        % Value changed function: TeachModeCheckBox
        function TeachModeCheckBoxValueChanged(app, event)
            app.teachMode = app.TeachModeCheckBox.Value;
        end

        % Button pushed function: UpButton
        function UpButtonPushed(app, event)
            if app.teachMode == 1
                app.cartesianAxis = 'Z';
                app.cartesianDirection = 'Pos';
                
                app.robot1.JogDobotCartesian(app.cartesianAxis, app.cartesianDirection, app.cartesianDisIncrement);
                app.UpdatePosition();
            end
        end

        % Button pushed function: DownButton
        function DownButtonPushed(app, event)
            if app.teachMode == 1
                app.cartesianAxis = 'Z';
                app.cartesianDirection = 'Neg';
                
                app.robot1.JogDobotCartesian(app.cartesianAxis, app.cartesianDirection, app.cartesianDisIncrement);
                app.UpdatePosition();
            end
        end

        % Button pushed function: LeftButton
        function LeftButtonPushed(app, event)
            if app.teachMode == 1
                app.cartesianAxis = 'Y';
                app.cartesianDirection = 'Pos';
                
                app.robot1.JogDobotCartesian(app.cartesianAxis, app.cartesianDirection, app.cartesianDisIncrement);
                app.UpdatePosition();
            end
        end

        % Button pushed function: RightButton
        function RightButtonPushed(app, event)
            if app.teachMode == 1
                app.cartesianAxis = 'Y';
                app.cartesianDirection = 'Neg';
                
                app.robot1.JogDobotCartesian(app.cartesianAxis, app.cartesianDirection, app.cartesianDisIncrement);
                app.UpdatePosition();
            end
        end

        % Button pushed function: BackButton
        function BackButtonPushed(app, event)
            if app.teachMode == 1
                app.cartesianAxis = 'X';
                app.cartesianDirection = 'Neg';
                
                app.robot1.JogDobotCartesian(app.cartesianAxis, app.cartesianDirection, app.cartesianDisIncrement);
                app.UpdatePosition();
            end
        end

        % Button pushed function: ForwardButton
        function ForwardButtonPushed(app, event)
            if app.teachMode == 1
                app.cartesianAxis = 'X';
                app.cartesianDirection = 'Pos';
                
                app.robot1.JogDobotCartesian(app.cartesianAxis, app.cartesianDirection, app.cartesianDisIncrement);
                app.UpdatePosition();
            end
        end

        % Button pushed function: RESUMEButton
        function RESUMEButtonPushed(app, event)
            app.robot1.ResumeDobot();
        end

        % Button pushed function: JOINT1Minus
        function JOINT1MinusButtonPushed(app, event)
            if app.teachMode == 1
                
                app.jointNumber = '1';
                app.jointDirection = 'Neg';
                
                app.robot1.JogDobotJoint(app.jointNumber, app.jointDirection, app.jointAngIncrement);
                app.UpdatePosition();
            end
        end

        % Button pushed function: JOINT1Plus
        function JOINT1PlusButtonPushed(app, event)
            if app.teachMode == 1
                
                app.jointNumber = '1';
                app.jointDirection = 'Pos';
                
                app.robot1.JogDobotJoint(app.jointNumber, app.jointDirection, app.jointAngIncrement);
                app.UpdatePosition();
            end
        end

        % Button pushed function: JOINT2Minus
        function JOINT2MinusButtonPushed(app, event)
            if app.teachMode == 1
                
                app.jointNumber = '2';
                app.jointDirection = 'Neg';
                
                app.robot1.JogDobotJoint(app.jointNumber, app.jointDirection, app.jointAngIncrement);
                app.UpdatePosition();
            end
        end

        % Button pushed function: JOINT2Plus
        function JOINT2PlusButtonPushed(app, event)
            if app.teachMode == 1
                
                app.jointNumber = '2';
                app.jointDirection = 'Pos';
                
                app.robot1.JogDobotJoint(app.jointNumber, app.jointDirection, app.jointAngIncrement);
                app.UpdatePosition();
            end
        end

        % Button pushed function: JOINT3Minus
        function JOINT3MinusButtonPushed(app, event)
            if app.teachMode == 1
                
                app.jointNumber = '3';
                app.jointDirection = 'Pos';
                
                app.robot1.JogDobotJoint(app.jointNumber, app.jointDirection, app.jointAngIncrement);
                app.UpdatePosition();
            end
        end

        % Button pushed function: JOINT3Plus
        function JOINT3PlusButtonPushed(app, event)
            if app.teachMode == 1
                
                app.jointNumber = '3';
                app.jointDirection = 'Neg';
                
                app.robot1.JogDobotJoint(app.jointNumber, app.jointDirection, app.jointAngIncrement);
                app.UpdatePosition();
            end
        end

        % Button pushed function: JOINT4Minus
        function JOINT4MinusButtonPushed(app, event)
            if app.teachMode == 1
                
                app.jointNumber = '4';
                app.jointDirection = 'Neg';
                
                app.robot1.JogDobotJoint(app.jointNumber, app.jointDirection, app.jointAngIncrement);
                app.UpdatePosition();
            end
        end

        % Button pushed function: JOINT4Plus
        function JOINT4PlusButtonPushed(app, event)
            if app.teachMode == 1
                
                app.jointNumber = '4';
                app.jointDirection = 'Pos';
                
                app.robot1.JogDobotJoint(app.jointNumber, app.jointDirection, app.jointAngIncrement);
                app.UpdatePosition();
            end
        end

        % Value changed function: R11
        function R11ValueChanged(app, event)
            app.robot1.SetRackState('1', 1, app.R11.Value);
            app.UpdateDropDowns();
        end

        % Value changed function: R12
        function R12ValueChanged(app, event)
            app.robot1.SetRackState('1', 2, app.R12.Value);
            app.UpdateDropDowns();
        end

        % Value changed function: R13
        function R13ValueChanged(app, event)
            app.robot1.SetRackState('1', 3, app.R13.Value);
            app.UpdateDropDowns();
        end

        % Value changed function: R14
        function R14ValueChanged(app, event)
            app.robot1.SetRackState('1', 4, app.R14.Value);
            app.UpdateDropDowns();
        end

        % Value changed function: R15
        function R15ValueChanged(app, event)
            app.robot1.SetRackState('1', 5, app.R15.Value);
            app.UpdateDropDowns();
        end

        % Value changed function: R16
        function R16ValueChanged(app, event)
            app.robot1.SetRackState('1', 6, app.R16.Value);
            app.UpdateDropDowns();
        end

        % Value changed function: R21
        function R21ValueChanged(app, event)
            app.robot1.SetRackState('2', 1, app.R21.Value);
            app.UpdateDropDowns();
        end

        % Value changed function: R22
        function R22ValueChanged(app, event)
            app.robot1.SetRackState('2', 2, app.R22.Value);
            app.UpdateDropDowns();
        end

        % Value changed function: R23
        function R23ValueChanged(app, event)
            app.robot1.SetRackState('2', 3, app.R23.Value);
            app.UpdateDropDowns();
        end

        % Value changed function: R24
        function R24ValueChanged(app, event)
            app.robot1.SetRackState('2', 4, app.R24.Value);
            app.UpdateDropDowns();
        end

        % Value changed function: R25
        function R25ValueChanged(app, event)
            app.robot1.SetRackState('2', 5, app.R25.Value);
            app.UpdateDropDowns();
        end

        % Value changed function: R26
        function R26ValueChanged(app, event)
            app.robot1.SetRackState('2', 6, app.R26.Value);
            app.UpdateDropDowns();
        end

        % Button pushed function: DISCONNECTButton
        function DISCONNECTButtonPushed(app, event)
            app.robot1.CompressorOff();
            disp('Disconnecting... Bye!');
            
            pause(2);
            rosshutdown;
        end

        % Button pushed function: GripperButton
        function GripperButtonPushed(app, event)
            app.robot1.OpenGripperDobot();
        end

        % Button pushed function: GripperButton_2
        function GripperButton_2Pushed(app, event)
            app.robot1.CloseGripperDobot();
        end

        % Button pushed function: PERFORMButton
        function PERFORMButtonPushed(app, event)
            fromPos = str2num(app.RACK1LDropDown.Value);
            toPos = str2num(app.RACK2LDropDown.Value);
            
            app.robot1.SetRackState('1', fromPos, 0);
            app.robot1.SetRackState('2', toPos, 1);
            
            app.robot1.PeformTestTubeMove('1', fromPos, '2', toPos);
            
            app.UpdateCheckBoxes();
            app.UpdateDropDowns();
            
        end

        % Button pushed function: CALIBRATERACKSButton
        function CALIBRATERACKSButtonPushed(app, event)
            app.robot1.CalibrateTestTubePositions();
        end

        % Button pushed function: LIFTDOBOTButton
        function LIFTDOBOTButtonPushed(app, event)
            endEffector = app.robot1.GetEndEffectorPosition();
            endEffector(3) = endEffector(3) + 0.1;
            
            app.robot1.MoveToCartesianPoint(endEffector);
        end

        % Button pushed function: PERFORMButton_2
        function PERFORMButton_2Pushed(app, event)
            fromPos = str2num(app.RACK2RDropDown.Value)
            toPos = str2num(app.RACK1RDropDown.Value)
            
            app.robot1.SetRackState('2', fromPos, 0);
            app.robot1.SetRackState('1', toPos, 1);
            
            app.robot1.PeformTestTubeMove('2', fromPos, '1', toPos);
            
            app.UpdateCheckBoxes();
            app.UpdateDropDowns();
            
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ESTOPButton = uibutton(app.UIFigure, 'push');
            app.ESTOPButton.ButtonPushedFcn = createCallbackFcn(app, @ESTOPButtonPushed, true);
            app.ESTOPButton.Text = 'ESTOP';
            app.HOMEButton_2 = uibutton(app.UIFigure, 'push');
            app.HOMEButton_2.ButtonPushedFcn = createCallbackFcn(app, @HOMEButton_2Pushed, true);
            app.HOMEButton_2.Text = 'HOME';
            app.PERFORMButton = uibutton(app.UIFigure, 'push');
            app.PERFORMButton.ButtonPushedFcn = createCallbackFcn(app, @PERFORMButtonPushed, true);
            app.PERFORMButton.Text = 'PERFORM';
            app.RACK1DropDownLabel = uilabel(app.UIFigure);
            app.RACK1DropDownLabel.Text = 'RACK 1';
            app.RACK1LDropDown = uidropdown(app.UIFigure);
            app.RACK2DropDownLabel = uilabel(app.UIFigure);
            app.RACK2DropDownLabel.Text = 'RACK 2';
            app.RACK2LDropDown = uidropdown(app.UIFigure);
            app.PERFORMButton_2 = uibutton(app.UIFigure, 'push');
            app.PERFORMButton_2.ButtonPushedFcn = createCallbackFcn(app, @PERFORMButton_2Pushed, true);
            app.PERFORMButton_2.Text = 'PERFORM';
            app.RACK2DropDown_3Label = uilabel(app.UIFigure);
            app.RACK2DropDown_3Label.Text = 'RACK 2';
            app.RACK2RDropDown = uidropdown(app.UIFigure);
            app.RACK1DropDown_2Label = uilabel(app.UIFigure);
            app.RACK1DropDown_2Label.Text = 'RACK 1';
            app.RACK1RDropDown = uidropdown(app.UIFigure);
            app.ForwardButton = uibutton(app.UIFigure, 'push');
            app.ForwardButton.ButtonPushedFcn = createCallbackFcn(app, @ForwardButtonPushed, true);
            app.ForwardButton.Text = 'Forward';
            app.BackButton = uibutton(app.UIFigure, 'push');
            app.BackButton.ButtonPushedFcn = createCallbackFcn(app, @BackButtonPushed, true);
            app.BackButton.Text = 'Back';
            app.RightButton = uibutton(app.UIFigure, 'push');
            app.RightButton.ButtonPushedFcn = createCallbackFcn(app, @RightButtonPushed, true);
            app.RightButton.Text = 'Right';
            app.LeftButton = uibutton(app.UIFigure, 'push');
            app.LeftButton.ButtonPushedFcn = createCallbackFcn(app, @LeftButtonPushed, true);
            app.LeftButton.Text = 'Left';
            app.TeachModeCheckBox = uicheckbox(app.UIFigure);
            app.TeachModeCheckBox.ValueChangedFcn = createCallbackFcn(app, @TeachModeCheckBoxValueChanged, true);
            app.TeachModeCheckBox.Text = 'Teach Mode';
            app.UpButton = uibutton(app.UIFigure, 'push');
            app.UpButton.ButtonPushedFcn = createCallbackFcn(app, @UpButtonPushed, true);
            app.UpButton.Text = 'Up';
            app.DownButton = uibutton(app.UIFigure, 'push');
            app.DownButton.ButtonPushedFcn = createCallbackFcn(app, @DownButtonPushed, true);
            app.DownButton.Text = 'Down';
            app.FromLabel = uilabel(app.UIFigure);
            app.FromLabel.Text = 'From';
            app.FromLabel_2 = uilabel(app.UIFigure);
            app.FromLabel_2.Text = 'From';
            app.ToLabel = uilabel(app.UIFigure);
            app.ToLabel.Text = 'To';
            app.ToLabel_2 = uilabel(app.UIFigure);
            app.ToLabel_2.Text = 'To';
            app.CARTESIANLabel = uilabel(app.UIFigure);
            app.CARTESIANLabel.Text = 'CARTESIAN';
            app.JOINTLabel = uilabel(app.UIFigure);
            app.JOINTLabel.Text = 'JOINT';
            app.JOINT1Minus = uibutton(app.UIFigure, 'push');
            app.JOINT1Minus.ButtonPushedFcn = createCallbackFcn(app, @JOINT1MinusButtonPushed, true);
            app.JOINT1Minus.Text = '-';
            app.JOINT1Label = uilabel(app.UIFigure);
            app.JOINT1Label.Text = 'JOINT 1:';
            app.JOINT1Plus = uibutton(app.UIFigure, 'push');
            app.JOINT1Plus.ButtonPushedFcn = createCallbackFcn(app, @JOINT1PlusButtonPushed, true);
            app.JOINT1Plus.Text = '+';
            app.JOINT2Minus = uibutton(app.UIFigure, 'push');
            app.JOINT2Minus.ButtonPushedFcn = createCallbackFcn(app, @JOINT2MinusButtonPushed, true);
            app.JOINT2Minus.Text = '-';
            app.JOINT2Label = uilabel(app.UIFigure);
            app.JOINT2Label.Text = 'JOINT 2:';
            app.JOINT2Plus = uibutton(app.UIFigure, 'push');
            app.JOINT2Plus.ButtonPushedFcn = createCallbackFcn(app, @JOINT2PlusButtonPushed, true);
            app.JOINT2Plus.Text = '+';
            app.JOINT3Minus = uibutton(app.UIFigure, 'push');
            app.JOINT3Minus.ButtonPushedFcn = createCallbackFcn(app, @JOINT3MinusButtonPushed, true);
            app.JOINT3Minus.Text = '-';
            app.JOINT3Label = uilabel(app.UIFigure);
            app.JOINT3Label.Text = 'JOINT 3:';
            app.JOINT3Plus = uibutton(app.UIFigure, 'push');
            app.JOINT3Plus.ButtonPushedFcn = createCallbackFcn(app, @JOINT3PlusButtonPushed, true);
            app.JOINT3Plus.Text = '+';
            app.JOINT4Minus = uibutton(app.UIFigure, 'push');
            app.JOINT4Minus.ButtonPushedFcn = createCallbackFcn(app, @JOINT4MinusButtonPushed, true);
            app.JOINT4Minus.Text = '-';
            app.JOINT4Label = uilabel(app.UIFigure);
            app.JOINT4Label.Text = 'JOINT 4:';
            app.JOINT4Plus = uibutton(app.UIFigure, 'push');
            app.JOINT4Plus.ButtonPushedFcn = createCallbackFcn(app, @JOINT4PlusButtonPushed, true);
            app.JOINT4Plus.Text = '+';
            app.IncrementDropDownLabel = uilabel(app.UIFigure);
            app.IncrementDropDownLabel.Text = 'Increment';
            app.IncrementDropDown = uidropdown(app.UIFigure);
            app.IncrementDropDown.Items = {'10', '5', '1'};
            app.IncrementDropDown.ValueChangedFcn = createCallbackFcn(app, @IncrementDropDownValueChanged, true);
            app.IncrementDropDown.Value = '10';
            app.IncrementDropDown_2Label = uilabel(app.UIFigure);
            app.IncrementDropDown_2Label.Text = 'Increment';
            app.IncrementDropDown_2 = uidropdown(app.UIFigure);
            app.IncrementDropDown_2.Items = {'10', '5', '1'};
            app.IncrementDropDown_2.ValueChangedFcn = createCallbackFcn(app, @IncrementDropDown_2ValueChanged, true);
            app.IncrementDropDown_2.Value = '10';
            app.XLabel = uilabel(app.UIFigure);
            app.XLabel.Text = 'X:';
            app.XEditField = uieditfield(app.UIFigure, 'text');
            app.CONNECTButton = uibutton(app.UIFigure, 'push');
            app.CONNECTButton.ButtonPushedFcn = createCallbackFcn(app, @CONNECTButtonPushed, true);
            app.CONNECTButton.Text = 'CONNECT';
            app.MMLabel = uilabel(app.UIFigure);
            app.MMLabel.Text = 'MM';
            app.DegLabel = uilabel(app.UIFigure);
            app.DegLabel.Text = 'Deg';
            app.StoppedLampLabel = uilabel(app.UIFigure);
            app.StoppedLampLabel.Text = 'Stopped';
            app.StoppedLamp = uilamp(app.UIFigure);
            app.RESUMEButton = uibutton(app.UIFigure, 'push');
            app.RESUMEButton.ButtonPushedFcn = createCallbackFcn(app, @RESUMEButtonPushed, true);
            app.RESUMEButton.Text = 'RESUME';
            app.R11 = uicheckbox(app.UIFigure);
            app.R11.ValueChangedFcn = createCallbackFcn(app, @R11ValueChanged, true);
            app.R11.Text = '1';
            app.RACK1Label = uilabel(app.UIFigure);
            app.RACK1Label.Text = 'RACK 1';
            app.R12 = uicheckbox(app.UIFigure);
            app.R12.ValueChangedFcn = createCallbackFcn(app, @R12ValueChanged, true);
            app.R12.Text = '2';
            app.R13 = uicheckbox(app.UIFigure);
            app.R13.ValueChangedFcn = createCallbackFcn(app, @R13ValueChanged, true);
            app.R13.Text = '3';
            app.R14 = uicheckbox(app.UIFigure);
            app.R14.ValueChangedFcn = createCallbackFcn(app, @R14ValueChanged, true);
            app.R14.Text = '4';
            app.R15 = uicheckbox(app.UIFigure);
            app.R15.ValueChangedFcn = createCallbackFcn(app, @R15ValueChanged, true);
            app.R15.Text = '5';
            app.R16 = uicheckbox(app.UIFigure);
            app.R16.ValueChangedFcn = createCallbackFcn(app, @R16ValueChanged, true);
            app.R16.Text = '6';
            app.R21 = uicheckbox(app.UIFigure);
            app.R21.ValueChangedFcn = createCallbackFcn(app, @R21ValueChanged, true);
            app.R21.Text = '1';
            app.RACK2Label = uilabel(app.UIFigure);
            app.RACK2Label.Text = 'RACK 2';
            app.R22 = uicheckbox(app.UIFigure);
            app.R22.ValueChangedFcn = createCallbackFcn(app, @R22ValueChanged, true);
            app.R22.Text = '2';
            app.R23 = uicheckbox(app.UIFigure);
            app.R23.ValueChangedFcn = createCallbackFcn(app, @R23ValueChanged, true);
            app.R23.Text = '3';
            app.R24 = uicheckbox(app.UIFigure);
            app.R24.ValueChangedFcn = createCallbackFcn(app, @R24ValueChanged, true);
            app.R24.Text = '4';
            app.R25 = uicheckbox(app.UIFigure);
            app.R25.ValueChangedFcn = createCallbackFcn(app, @R25ValueChanged, true);
            app.R25.Text = '5';
            app.R26 = uicheckbox(app.UIFigure);
            app.R26.ValueChangedFcn = createCallbackFcn(app, @R26ValueChanged, true);
            app.R26.Text = '6';
            app.YEditFieldLabel = uilabel(app.UIFigure);
            app.YEditFieldLabel.Text = 'Y:';
            app.YEditField = uieditfield(app.UIFigure, 'text');
            app.ZLabel = uilabel(app.UIFigure);
            app.ZLabel.Text = 'Z:';
            app.ZEditField = uieditfield(app.UIFigure, 'text');
            app.POSITIONLabel = uilabel(app.UIFigure);
            app.POSITIONLabel.Text = 'POSITION';
            app.J1EditFieldLabel = uilabel(app.UIFigure);
            app.J1EditFieldLabel.Text = 'J1:';
            app.J1EditField = uieditfield(app.UIFigure, 'text');
            app.J2EditFieldLabel = uilabel(app.UIFigure);
            app.J2EditFieldLabel.Text = 'J2:';
            app.J2EditField = uieditfield(app.UIFigure, 'text');
            app.J3EditFieldLabel = uilabel(app.UIFigure);
            app.J3EditFieldLabel.Text = 'J3:';
            app.J3EditField = uieditfield(app.UIFigure, 'text');
            app.J4EditFieldLabel = uilabel(app.UIFigure);
            app.J4EditFieldLabel.Text = 'J4:';
            app.J4EditField = uieditfield(app.UIFigure, 'text');
            app.DISCONNECTButton = uibutton(app.UIFigure, 'push');
            app.DISCONNECTButton.ButtonPushedFcn = createCallbackFcn(app, @DISCONNECTButtonPushed, true);
            app.DISCONNECTButton.Text = 'DISCONNECT';
            app.CALIBRATERACKSButton = uibutton(app.UIFigure, 'push');
            app.CALIBRATERACKSButton.ButtonPushedFcn = createCallbackFcn(app, @CALIBRATERACKSButtonPushed, true);
            app.CALIBRATERACKSButton.Text = {'CALIBRATE '; 'RACKS'};
            app.GripperButton = uibutton(app.UIFigure, 'push');
            app.GripperButton.ButtonPushedFcn = createCallbackFcn(app, @GripperButtonPushed, true);
            app.GripperButton.Text = 'Gripper';
            app.GripperButton_2 = uibutton(app.UIFigure, 'push');
            app.GripperButton_2.ButtonPushedFcn = createCallbackFcn(app, @GripperButton_2Pushed, true);
            app.GripperButton_2.Text = 'Gripper';
            app.LIFTDOBOTButton = uibutton(app.UIFigure, 'push');
            app.LIFTDOBOTButton.ButtonPushedFcn = createCallbackFcn(app, @LIFTDOBOTButtonPushed, true);
            app.LIFTDOBOTButton.Text = 'LIFT DOBOT';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end